classdef RegOA_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RegOA_appUIFigure       matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        loadOATButton           matlab.ui.control.Button
        loadMRIButton           matlab.ui.control.Button
        DisplayDropDownLabel    matlab.ui.control.Label
        DisplayDropDown         matlab.ui.control.DropDown
        DisplayDropDown_2Label  matlab.ui.control.Label
        DisplayDropDown_2       matlab.ui.control.DropDown
        RegistrationButton      matlab.ui.control.Button
        PreprocessingButton     matlab.ui.control.Button
        UIAxes_3                matlab.ui.control.UIAxes
        NIIoutputButton         matlab.ui.control.Button
        EvaluationButton        matlab.ui.control.Button
        Lamp                    matlab.ui.control.Lamp
        Lamp_2                  matlab.ui.control.Lamp
        Lamp_3                  matlab.ui.control.Lamp
        Lamp_4                  matlab.ui.control.Lamp
        Lamp_5                  matlab.ui.control.Lamp
        DOATButton              matlab.ui.control.Button
        DMRIButton              matlab.ui.control.Button
        DisplayDropDown_3Label  matlab.ui.control.Label
        DisplayDropDown_3       matlab.ui.control.DropDown
        SaveButton_2            matlab.ui.control.Button
        Lamp_6                  matlab.ui.control.Lamp
        RegOAaregistrationtoolforOATLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        regOA_obj
    end

    methods (Access = private)

        % Code that executes after component creation
        function initiateRegOA(app)
            app.regOA_obj = RegOA;
            app.regOA_obj.configRegOA;
            app.regOA_obj.MSOT_seg_MaxIteration   =100;
            app.regOA_obj.MRI_seg_MaxIteration   =50;
        end

        % Button pushed function: loadOATButton
        function loadMSOT(app, event)
            app.regOA_obj.readMSOTRegOA;
            app.Lamp.Color = 'green';
            app.Lamp_3.Color = 'red';
            app.Lamp_4.Color = 'red';
            app.Lamp_5.Color = 'red';
            app.Lamp_6.Color = 'red';
            title(app.UIAxes, 'OAT loaded');
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow(app.regOA_obj.MSOT_dataset(:,:,ceil(app.regOA_obj.MSOT_n_z/2)), 'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: loadMRIButton
        function loadMRI(app, event)
            app.regOA_obj.readMRIRegOA;
            app.Lamp_2.Color = 'green';
            app.Lamp_3.Color = 'red';
            app.Lamp_4.Color = 'red';
            app.Lamp_5.Color = 'red';
            app.Lamp_6.Color = 'red';
            title(app.UIAxes_2, 'MRI loaded');
            xlabel(app.UIAxes_2, []);
            ylabel(app.UIAxes_2, []);
            app.UIAxes_2.XAxis.TickLabels = {};
            app.UIAxes_2.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow(app.regOA_obj.MRI_dataset(:,:,ceil(app.regOA_obj.MRI_n_z/2)), 'Parent', app.UIAxes_2,'XData', [1 app.UIAxes_2.Position(3)],'YData', [1 app.UIAxes_2.Position(4)]);
            % Set limits of axes
            app.UIAxes_2.XLim = [0 I.XData(2)];
            app.UIAxes_2.YLim = [0 I.YData(2)];
        end

        % Button pushed function: PreprocessingButton
        function Pre_processing(app, event)
            app.Lamp_4.Color = 'red';
            app.Lamp_5.Color = 'red';
            app.Lamp_6.Color = 'red';
            app.regOA_obj.prepMSOTRegOA;
            app.regOA_obj.prepMRIRegOA;
            app.Lamp_3.Color = 'green';
            
        end

        % Button pushed function: RegistrationButton
        function registration_MSOT_MRI(app, event)
            app.regOA_obj.registRegOA;
            app.Lamp_4.Color = 'green';
            app.Lamp_5.Color = 'red';
            app.Lamp_6.Color = 'red';
            title(app.UIAxes_3, 'OAT-MRI registration');
            xlabel(app.UIAxes_3, []);
            ylabel(app.UIAxes_3, []);
            app.UIAxes_3.XAxis.TickLabels = {};
            app.UIAxes_3.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            result_show = zeros(app.regOA_obj.image_size,app.regOA_obj.image_size,3);
            result_show(:,:,1) = app.regOA_obj.MSOT_reg_slice_result;
            result_show(:,:,2) = app.regOA_obj.MRI_reg_slice_result;
            I = imshow(result_show, 'Parent', app.UIAxes_3,'XData', [1 app.UIAxes_3.Position(3)],'YData', [1 app.UIAxes_3.Position(4)]);
            % Set limits of axes
            app.UIAxes_3.XLim = [0 I.XData(2)];
            app.UIAxes_3.YLim = [0 I.YData(2)];
        end

        % Button pushed function: NIIoutputButton
        function NIIoutput(app, event)
            app.regOA_obj.outputRegOA;
            app.Lamp_5.Color = 'green';
        end

        % Button pushed function: DOATButton
        function MSOT_visualize(app, event)
            if (app.Lamp.Color(2) == 1) && (app.Lamp_3.Color(2)==1)
                figure(1),imshow3Dfull_color(app.regOA_obj.MSOT_seg,[],'hot');
            else 
                disp('3D OAT not ready yet')
            end
        end

        % Button pushed function: DMRIButton
        function MRI_visualize(app, event)
            if (app.Lamp_2.Color(2) == 1) && (app.Lamp_3.Color(2)==1)
                figure(2),imshow3Dfull_color(app.regOA_obj.MRI_seg,[],'gray');
            else 
                disp('3D MRI not ready yet')
            end            
        end

        % Value changed function: DisplayDropDown
        function Display_MSOT_option(app, event)
            value = app.DisplayDropDown.Value;
            if (app.Lamp.Color(2) == 1) && (app.Lamp_3.Color(2)==1)
                switch value
                    case 'Raw'
                        xlabel(app.UIAxes, []);
                        ylabel(app.UIAxes, []);
                        app.UIAxes.XAxis.TickLabels = {};
                        app.UIAxes.YAxis.TickLabels = {};
                        I = imshow(app.regOA_obj.MSOT_dataset(:,:,ceil(app.regOA_obj.MSOT_n_z/2)), 'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
                        % Set limits of axes
                        app.UIAxes.XLim = [0 I.XData(2)];
                        app.UIAxes.YLim = [0 I.YData(2)];                                              
                    case 'Segmented'
                        xlabel(app.UIAxes, []);
                        ylabel(app.UIAxes, []);
                        app.UIAxes.XAxis.TickLabels = {};
                        app.UIAxes.YAxis.TickLabels = {};
                        I = imshow(app.regOA_obj.MSOT_seg(:,:,ceil(app.regOA_obj.MSOT_n_z/2)), 'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
                        % Set limits of axes
                        app.UIAxes.XLim = [0 I.XData(2)];
                        app.UIAxes.YLim = [0 I.YData(2)];                               
                    case 'Mask'
                        xlabel(app.UIAxes, []);
                        ylabel(app.UIAxes, []);
                        app.UIAxes.XAxis.TickLabels = {};
                        app.UIAxes.YAxis.TickLabels = {};
                        I = imshow(app.regOA_obj.MSOT_mask(:,:,ceil(app.regOA_obj.MSOT_n_z/2)), 'Parent', app.UIAxes,'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
                        % Set limits of axes
                        app.UIAxes.XLim = [0 I.XData(2)];
                        app.UIAxes.YLim = [0 I.YData(2)];                               
                end
            else 
                disp('3D OAT not ready yet')
            end
        end

        % Value changed function: DisplayDropDown_2
        function Display_MRI_option(app, event)
            value = app.DisplayDropDown_2.Value;
                        if (app.Lamp.Color(2) == 1) && (app.Lamp_3.Color(2)==1)
                switch value
                    case 'Raw'
                        xlabel(app.UIAxes_2, []);
                        ylabel(app.UIAxes_2, []);
                        app.UIAxes_2.XAxis.TickLabels = {};
                        app.UIAxes_2.YAxis.TickLabels = {};
                        I = imshow(app.regOA_obj.MRI_dataset(:,:,ceil(app.regOA_obj.MRI_n_z/2)), 'Parent', app.UIAxes_2,'XData', [1 app.UIAxes_2.Position(3)],'YData', [1 app.UIAxes_2.Position(4)]);
                        % Set limits of axes
                        app.UIAxes_2.XLim = [0 I.XData(2)];
                        app.UIAxes_2.YLim = [0 I.YData(2)];                                              
                    case 'Segmented'
                        xlabel(app.UIAxes_2, []);
                        ylabel(app.UIAxes_2, []);
                        app.UIAxes_2.XAxis.TickLabels = {};
                        app.UIAxes_2.YAxis.TickLabels = {};
                        I = imshow(app.regOA_obj.MRI_seg(:,:,ceil(app.regOA_obj.MRI_n_z/2)), 'Parent', app.UIAxes_2,'XData', [1 app.UIAxes_2.Position(3)],'YData', [1 app.UIAxes_2.Position(4)]);
                        % Set limits of axes
                        app.UIAxes_2.XLim = [0 I.XData(2)];
                        app.UIAxes_2.YLim = [0 I.YData(2)];                               
                    case 'Mask'
                        xlabel(app.UIAxes_2, []);
                        ylabel(app.UIAxes_2, []);
                        app.UIAxes_2.XAxis.TickLabels = {};
                        app.UIAxes_2.YAxis.TickLabels = {};
                        I = imshow(app.regOA_obj.MRI_mask(:,:,ceil(app.regOA_obj.MRI_n_z/2)), 'Parent', app.UIAxes_2,'XData', [1 app.UIAxes_2.Position(3)],'YData', [1 app.UIAxes_2.Position(4)]);
                        % Set limits of axes
                        app.UIAxes_2.XLim = [0 I.XData(2)];
                        app.UIAxes_2.YLim = [0 I.YData(2)];                               
                end
            else 
                disp('3D OAT not ready yet')
            end
        end

        % Button pushed function: EvaluationButton
        function Evaluation_reg(app, event)
            
            MRI_mask_reg_slice = app.regOA_obj.MRI_mask(:,:,app.regOA_obj.MRI_reg_slice_num);
            MSOT_mask_reg_slice = app.regOA_obj.MSOT_mask(:,:,app.regOA_obj.MSOT_reg_slice_num);
            [optimizer_1,metric_1] = imregconfig('monomodal');
            tform_1 = imregtform(MSOT_mask_reg_slice,MRI_mask_reg_slice,'affine',optimizer_1,metric_1);
            MSOT_reg_mask_result_1 = imwarp(app.regOA_obj.MSOT_reg_slice,tform_1,'OutputView',imref2d(size(MRI_mask_reg_slice)), 'SmoothEdges', true);
            %MSOT_mask_reg_slice_result = imwarp(MSOT_mask_reg_slice,tform_1,'OutputView',imref2d(size(MRI_mask_reg_slice)), 'SmoothEdges', true);
            [optimizer_2,metric_2] = imregconfig('multimodal');
            tform_2 = imregtform(MSOT_reg_mask_result_1,app.regOA_obj.MRI_reg_slice,'rigid',optimizer_2,metric_2);
            %MSOT_reg_mask_result_2 =imwarp(MSOT_reg_mask_result_1,tform_2,'OutputView',imref2d(size(app.regOA_obj.MSOT_reg_slice)), 'SmoothEdges', true);
            disp('number of landmark pairs: ')
            n_pair = input(' ');
            x_MSOT = zeros(n_pair,1);
            y_MSOT = zeros(n_pair,1);
            x_MRI = zeros(n_pair,1);
            y_MRI = zeros(n_pair,1);
            Landmark_MSOT = zeros(app.regOA_obj.image_size,app.regOA_obj.image_size);
            Landmark_MRI = zeros(app.regOA_obj.image_size,app.regOA_obj.image_size);
            h1=figure;
            imagesc(app.regOA_obj.MSOT_reg_slice);
            for i = 1: n_pair
                [x_MSOT(i),y_MSOT(i)] = ginput(1);
                x_MSOT(i) = ceil(x_MSOT(i));
                y_MSOT(i) = ceil(y_MSOT(i));
                hold on
                plot(x_MSOT(i),y_MSOT(i),'+r');
                hold off
                %Landmark_MSOT(y_MSOT(i),x_MSOT(i)) = 1;
                Landmark_MSOT((y_MSOT(i)-5):(y_MSOT(i)+5),(x_MSOT(i)-5):(x_MSOT(i)+5)) = 1;
            end
            close(h1)
            h2=figure;
            imagesc(app.regOA_obj.MRI_reg_slice);
            for i = 1: n_pair
                [x_MRI(i),y_MRI(i)] = ginput(1);
                x_MRI(i) = ceil(x_MRI(i));
                y_MRI(i) = ceil(y_MRI(i));
                hold on
                plot(x_MRI(i),y_MRI(i),'+r');
                hold off
                %Landmark_MRI(y_MRI(i),x_MRI(i)) = 1;
                Landmark_MRI((y_MRI(i)-5):(y_MRI(i)+5),(x_MRI(i)-5):(x_MRI(i)+5)) = 2;
            end
            close(h2)
            Original_Target_error = [x_MSOT y_MSOT]'-[x_MRI y_MRI]';
            Ori_Target_distance = sum(Original_Target_error.^2).^0.5;
            Ori_Target_distance_mean = mean(Ori_Target_distance);
            Ori_Target_distance_std = std(Ori_Target_distance);
            disp(['original distance: ' num2str(Ori_Target_distance_mean) '+/-' num2str(Ori_Target_distance_std)]);
            Landmark_MSOT_M3_1 = tform_1.T'*[x_MSOT';y_MSOT';ones(1,n_pair)];
            TRE_matrix_M3_1 = Landmark_MSOT_M3_1(1:2,:)-[x_MRI y_MRI]';
            TRE_distance_M3_1 = sum(TRE_matrix_M3_1.^2).^0.5;
            TRE_distance_M3_1_mean = mean(TRE_distance_M3_1);
            TRE_distance_M3_1_std = std(TRE_distance_M3_1);
            EER_M3_1 = (TRE_distance_M3_1./Ori_Target_distance) *100; % unit is XX%
            EER_M3_1_mean = mean(EER_M3_1);
            EER_M3_1_std = std(EER_M3_1);
            disp(['M3 step 1 TRE: '  num2str(TRE_distance_M3_1_mean) '+/-' num2str(TRE_distance_M3_1_std)]);
            disp(['M3 step 1 EER: '  num2str(EER_M3_1_mean) '+/-' num2str(EER_M3_1_std) ' %']);
            
            Landmark_MSOT_M3_2 = tform_2.T'*Landmark_MSOT_M3_1;
            TRE_matrix_M3_2 = Landmark_MSOT_M3_2(1:2,:)-[x_MRI y_MRI]';
            TRE_distance_M3_2 = sum(TRE_matrix_M3_2.^2).^0.5;
            TRE_distance_M3_2_mean = mean(TRE_distance_M3_2);
            TRE_distance_M3_2_std = std(TRE_distance_M3_2);
            EER_M3_2 = (TRE_distance_M3_2./Ori_Target_distance) *100; % unit is XX%
            EER_M3_2_mean = mean(EER_M3_2);
            EER_M3_2_std = std(EER_M3_2);
            disp(['M3 step 2 TRE: '  num2str(TRE_distance_M3_2_mean) '+/-' num2str(TRE_distance_M3_2_std)]);
            disp(['M3 step 2 EER: '  num2str(EER_M3_2_mean) '+/-' num2str(EER_M3_2_std) ' %']);
            
            app.Lamp_6.Color = 'green';
        end

        % Value changed function: DisplayDropDown_3
        function Regis_visual(app, event)
            value = app.DisplayDropDown_3.Value;
            if (app.Lamp_4.Color(2) == 1)
                switch value
                    case 'Overlay'
                        xlabel(app.UIAxes_3, []);
                        ylabel(app.UIAxes_3, []);
                        app.UIAxes_3.XAxis.TickLabels = {};
                        app.UIAxes_3.YAxis.TickLabels = {};
                        result_show = zeros(app.regOA_obj.image_size,app.regOA_obj.image_size,3);
                        result_show(:,:,1) = app.regOA_obj.MSOT_reg_slice_result;
                        result_show(:,:,2) = app.regOA_obj.MRI_reg_slice_result;
                        I = imshow(result_show, 'Parent', app.UIAxes_3,'XData', [1 app.UIAxes_3.Position(3)],'YData', [1 app.UIAxes_3.Position(4)]);
                        % Set limits of axes
                        app.UIAxes_3.XLim = [0 I.XData(2)];
                        app.UIAxes_3.YLim = [0 I.YData(2)];                                              
                    case 'MRI'
                        xlabel(app.UIAxes_3, []);
                        ylabel(app.UIAxes_3, []);
                        app.UIAxes_3.XAxis.TickLabels = {};
                        app.UIAxes_3.YAxis.TickLabels = {};
                        I = imshow(app.regOA_obj.MRI_reg_slice_result, 'Parent', app.UIAxes_3,'XData', [1 app.UIAxes_3.Position(3)],'YData', [1 app.UIAxes_3.Position(4)]);
                        % Set limits of axes
                        app.UIAxes_3.XLim = [0 I.XData(2)];
                        app.UIAxes_3.YLim = [0 I.YData(2)];                               
                    case 'MSOT'
                        xlabel(app.UIAxes_3, []);
                        ylabel(app.UIAxes_3, []);
                        app.UIAxes_3.XAxis.TickLabels = {};
                        app.UIAxes_3.YAxis.TickLabels = {};
                        I = imshow(app.regOA_obj.MSOT_reg_slice_result, 'Parent', app.UIAxes_3,'XData', [1 app.UIAxes_3.Position(3)],'YData', [1 app.UIAxes_3.Position(4)]);
                        % Set limits of axes
                        app.UIAxes_3.XLim = [0 I.XData(2)];
                        app.UIAxes_3.YLim = [0 I.YData(2)];                               
                end
            else 
                disp('registration result not ready')
            end
        end

        % Button pushed function: SaveButton_2
        function Save_img(app, event)
            img1 = app.regOA_obj.MSOT_dataset(:,:,app.regOA_obj.MSOT_reg_slice_num);
            img2 = app.regOA_obj.MRI_dataset(:,:,app.regOA_obj.MRI_reg_slice_num);
            img3 = app.regOA_obj.MSOT_reg_slice_result;
            img4 = app.regOA_obj.MRI_reg_slice_result;
            disp('name your output folder (images): ')
            Output_folder = input(' ','s');
            mkdir(Output_folder);
            save([Output_folder '/saved_images.mat'],'img1','img2','img3','img4');    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RegOA_appUIFigure = uifigure;
            app.RegOA_appUIFigure.Name = 'RegOA_app';
            app.UIAxes = uiaxes(app.RegOA_appUIFigure);
            title(app.UIAxes, 'OAT')
            app.UIAxes_2 = uiaxes(app.RegOA_appUIFigure);
            title(app.UIAxes_2, 'MRI')
            app.loadOATButton = uibutton(app.RegOA_appUIFigure, 'push');
            app.loadOATButton.ButtonPushedFcn = createCallbackFcn(app, @loadMSOT, true);
            app.loadOATButton.Text = 'load OAT';
            app.loadMRIButton = uibutton(app.RegOA_appUIFigure, 'push');
            app.loadMRIButton.ButtonPushedFcn = createCallbackFcn(app, @loadMRI, true);
            app.loadMRIButton.Text = 'load MRI';
            app.DisplayDropDownLabel = uilabel(app.RegOA_appUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.RegOA_appUIFigure);
            app.DisplayDropDown.Items = {'Raw', 'Segmented', 'Mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @Display_MSOT_option, true);
            app.DisplayDropDown.Value = 'Raw';
            app.DisplayDropDown_2Label = uilabel(app.RegOA_appUIFigure);
            app.DisplayDropDown_2Label.Text = 'Display';
            app.DisplayDropDown_2 = uidropdown(app.RegOA_appUIFigure);
            app.DisplayDropDown_2.Items = {'Raw', 'Segmented', 'Mask'};
            app.DisplayDropDown_2.ValueChangedFcn = createCallbackFcn(app, @Display_MRI_option, true);
            app.DisplayDropDown_2.Value = 'Raw';
            app.RegistrationButton = uibutton(app.RegOA_appUIFigure, 'push');
            app.RegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @registration_MSOT_MRI, true);
            app.RegistrationButton.Text = 'Registration';
            app.PreprocessingButton = uibutton(app.RegOA_appUIFigure, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @Pre_processing, true);
            app.PreprocessingButton.Text = 'Pre-processing';
            app.UIAxes_3 = uiaxes(app.RegOA_appUIFigure);
            title(app.UIAxes_3, 'OAT-MRI registration')
            app.NIIoutputButton = uibutton(app.RegOA_appUIFigure, 'push');
            app.NIIoutputButton.ButtonPushedFcn = createCallbackFcn(app, @NIIoutput, true);
            app.NIIoutputButton.Text = 'NII output';
            app.EvaluationButton = uibutton(app.RegOA_appUIFigure, 'push');
            app.EvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @Evaluation_reg, true);
            app.EvaluationButton.Text = 'Evaluation';
            app.Lamp = uilamp(app.RegOA_appUIFigure);
            app.Lamp.Enable = 'off';
            app.Lamp_2 = uilamp(app.RegOA_appUIFigure);
            app.Lamp_2.Enable = 'off';
            app.Lamp_3 = uilamp(app.RegOA_appUIFigure);
            app.Lamp_3.Enable = 'off';
            app.Lamp_4 = uilamp(app.RegOA_appUIFigure);
            app.Lamp_4.Enable = 'off';
            app.Lamp_5 = uilamp(app.RegOA_appUIFigure);
            app.Lamp_5.Enable = 'off';
            app.DOATButton = uibutton(app.RegOA_appUIFigure, 'push');
            app.DOATButton.ButtonPushedFcn = createCallbackFcn(app, @MSOT_visualize, true);
            app.DOATButton.Text = '3D OAT';
            app.DMRIButton = uibutton(app.RegOA_appUIFigure, 'push');
            app.DMRIButton.ButtonPushedFcn = createCallbackFcn(app, @MRI_visualize, true);
            app.DMRIButton.Text = '3D MRI';
            app.DisplayDropDown_3Label = uilabel(app.RegOA_appUIFigure);
            app.DisplayDropDown_3Label.Text = 'Display';
            app.DisplayDropDown_3 = uidropdown(app.RegOA_appUIFigure);
            app.DisplayDropDown_3.Items = {'MSOT', 'MRI', 'Overlay'};
            app.DisplayDropDown_3.ValueChangedFcn = createCallbackFcn(app, @Regis_visual, true);
            app.DisplayDropDown_3.Value = 'Overlay';
            app.SaveButton_2 = uibutton(app.RegOA_appUIFigure, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @Save_img, true);
            app.SaveButton_2.Text = 'Save';
            app.Lamp_6 = uilamp(app.RegOA_appUIFigure);
            app.Lamp_6.Enable = 'off';
            app.RegOAaregistrationtoolforOATLabel = uilabel(app.RegOA_appUIFigure);
            app.RegOAaregistrationtoolforOATLabel.Text = '©RegOA: a registration tool for OAT';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RegOA_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RegOA_appUIFigure)

            % Execute the startup function
            runStartupFcn(app, @initiateRegOA)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RegOA_appUIFigure)
        end
    end
end